classdef Calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Panel         matlab.ui.container.Panel
        Panel_2       matlab.ui.container.Panel
        D7            matlab.ui.control.Button
        D8            matlab.ui.control.Button
        D9            matlab.ui.control.Button
        D4            matlab.ui.control.Button
        D5            matlab.ui.control.Button
        D6            matlab.ui.control.Button
        D1            matlab.ui.control.Button
        D2            matlab.ui.control.Button
        D3            matlab.ui.control.Button
        Dot           matlab.ui.control.Button
        D0            matlab.ui.control.Button
        clear         matlab.ui.control.Button
        Plus          matlab.ui.control.Button
        Minus         matlab.ui.control.Button
        Mult          matlab.ui.control.Button
        Div           matlab.ui.control.Button
        Result        matlab.ui.control.Button
        Panel_3       matlab.ui.container.Panel
        Label_result  matlab.ui.control.EditField
    end

    
    properties (Access = private)
        Dotnumber;
        enableDot = 0;
        integerNumber = 0;
        num1;
        num2;
        sign;
        
         
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: D1
        function D1ButtonPushed(app, event)
            result = '0';
            if app.enableDot == 1
                app.Dotnumber = [app.Dotnumber '1']
                result = [num2str(app.integerNumber) '.' app.Dotnumber];
            else
                result = [app.Label_result.Value '1'];
            end
            
            newr = str2num(result);
            app.Label_result.Value = num2str(newr);
        end

        % Button pushed function: D2
        function D2ButtonPushed(app, event)
            result = '0';
            if app.enableDot == 1
                app.Dotnumber = [app.Dotnumber '2']
                result = [num2str(app.integerNumber) '.' app.Dotnumber];
            else
                result = [app.Label_result.Value '2'];
            end
            newr = str2num(result);
            app.Label_result.Value = num2str(newr);
        end

        % Button pushed function: D3
        function D3ButtonPushed(app, event)
            result = '0';
            if app.enableDot == 1
                app.Dotnumber = [app.Dotnumber '3']
                result = [num2str(app.integerNumber) '.' app.Dotnumber];
            else
                result = [app.Label_result.Value '3'];
            end
            newr = str2num(result);
            app.Label_result.Value = num2str(newr);
        end

        % Button pushed function: D4
        function D4ButtonPushed(app, event)
            result = '0';
            if app.enableDot == 1
                app.Dotnumber = [app.Dotnumber '4']
                result = [num2str(app.integerNumber) '.' app.Dotnumber];
            else
                result = [app.Label_result.Value '4'];
            end
            newr = str2num(result);
            app.Label_result.Value = num2str(newr);
        end

        % Button pushed function: D5
        function D5ButtonPushed(app, event)
            result = '0';
            if app.enableDot == 1
                app.Dotnumber = [app.Dotnumber '5']
                result = [num2str(app.integerNumber) '.' app.Dotnumber];
            else
                result = [app.Label_result.Value '5'];
            end
            newr = str2num(result);
            app.Label_result.Value = num2str(newr);
        end

        % Button pushed function: D6
        function D6ButtonPushed(app, event)
            result = '0';
            if app.enableDot == 1
                app.Dotnumber = [app.Dotnumber '6']
                result = [num2str(app.integerNumber) '.' app.Dotnumber];
            else
                result = [app.Label_result.Value '6'];
            end
            newr = str2num(result);
            app.Label_result.Value = num2str(newr);
        end

        % Button pushed function: D7
        function D7ButtonPushed(app, event)
            result = '0';
            if app.enableDot == 1
                app.Dotnumber = [app.Dotnumber '7']
                result = [num2str(app.integerNumber) '.' app.Dotnumber];
            else
                result = [app.Label_result.Value '7'];
            end
            newr = str2num(result);
            app.Label_result.Value = num2str(newr);
        end

        % Button pushed function: D8
        function D8ButtonPushed(app, event)
            result = '0';
            if app.enableDot == 1
                app.Dotnumber = [app.Dotnumber '8']
                result = [num2str(app.integerNumber) '.' app.Dotnumber];
            else
                result = [app.Label_result.Value '8'];
            end
            newr = str2num(result);
            app.Label_result.Value = num2str(newr);
        end

        % Button pushed function: D9
        function D9ButtonPushed(app, event)
            result = '0';
            if app.enableDot == 1
                app.Dotnumber = [app.Dotnumber '9']
                result = [num2str(app.integerNumber) '.' app.Dotnumber];
            else
                result = [app.Label_result.Value '9'];
            end
            newr = str2num(result);
            app.Label_result.Value = num2str(newr);
        end

        % Button pushed function: D0
        function D0ButtonPushed(app, event)
            result = '0';
            if app.enableDot == 1
                app.Dotnumber = [app.Dotnumber '0']
                result = [num2str(app.integerNumber) '.' app.Dotnumber];
            else
                result = [app.Label_result.Value '0'];
            end
            newr = str2num(result);
            app.Label_result.Value = num2str(newr);
        end

        % Button pushed function: Dot
        function DotButtonPushed(app, event)
            app.enableDot = 1;
            app.integerNumber = str2num(app.Label_result.Value);
        end

        % Button pushed function: clear
        function clearButtonPushed(app, event)
            app.Label_result.Value = '0';
            app.integerNumber = 0;
            app.Dotnumber = '';
            app.enableDot = 0;
        end

        % Button pushed function: Plus
        function PlusButtonPushed(app, event)
            app.sign = '+';
            app.num1 = str2num(app.Label_result.Value);
            app.Label_result.Value = '0';
            app.integerNumber = 0;
            app.Dotnumber = '';
            app.enableDot = 0;
            
        end

        % Button pushed function: Result
        function ResultButtonPushed(app, event)
            app.num2 = str2num(app.Label_result.Value);
            
            if  app.sign == '+'
                app.Label_result.Value = num2str(app.num1 + app.num2);
            elseif app.sign == '-'
                app.Label_result.Value = num2str(app.num1 - app.num2);
            elseif app.sign == '*'
                app.Label_result.Value = num2str(app.num1 * app.num2);
            elseif app.sign == '/'
                app.Label_result.Value = num2str(app.num1 / app.num2);
            end
        end

        % Button pushed function: Minus
        function MinusButtonPushed(app, event)
            app.sign = '-';
            app.num1 = str2num(app.Label_result.Value);
            app.Label_result.Value = '0';
            app.integerNumber = 0;
            app.Dotnumber = '';
            app.enableDot = 0;
        end

        % Button pushed function: Mult
        function MultButtonPushed(app, event)
            app.sign = '*';
            app.num1 = str2num(app.Label_result.Value);
            app.Label_result.Value = '0';
            app.integerNumber = 0;
            app.Dotnumber = '';
            app.enableDot = 0;
        end

        % Button pushed function: Div
        function DivButtonPushed(app, event)
            app.sign = '/';
            app.num1 = str2num(app.Label_result.Value);
            app.Label_result.Value = '0';
            app.integerNumber = 0;
            app.Dotnumber = '';
            app.enableDot = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.Panel_2 = uipanel(app.Panel);
            app.D7 = uibutton(app.Panel_2, 'push');
            app.D7.ButtonPushedFcn = createCallbackFcn(app, @D7ButtonPushed, true);
            app.D7.Text = '7';
            app.D8 = uibutton(app.Panel_2, 'push');
            app.D8.ButtonPushedFcn = createCallbackFcn(app, @D8ButtonPushed, true);
            app.D8.Text = '8';
            app.D9 = uibutton(app.Panel_2, 'push');
            app.D9.ButtonPushedFcn = createCallbackFcn(app, @D9ButtonPushed, true);
            app.D9.Text = '9';
            app.D4 = uibutton(app.Panel_2, 'push');
            app.D4.ButtonPushedFcn = createCallbackFcn(app, @D4ButtonPushed, true);
            app.D4.Text = '4';
            app.D5 = uibutton(app.Panel_2, 'push');
            app.D5.ButtonPushedFcn = createCallbackFcn(app, @D5ButtonPushed, true);
            app.D5.Text = '5';
            app.D6 = uibutton(app.Panel_2, 'push');
            app.D6.ButtonPushedFcn = createCallbackFcn(app, @D6ButtonPushed, true);
            app.D6.Text = '6';
            app.D1 = uibutton(app.Panel_2, 'push');
            app.D1.ButtonPushedFcn = createCallbackFcn(app, @D1ButtonPushed, true);
            app.D1.Text = '1';
            app.D2 = uibutton(app.Panel_2, 'push');
            app.D2.ButtonPushedFcn = createCallbackFcn(app, @D2ButtonPushed, true);
            app.D2.Text = '2';
            app.D3 = uibutton(app.Panel_2, 'push');
            app.D3.ButtonPushedFcn = createCallbackFcn(app, @D3ButtonPushed, true);
            app.D3.Text = '3';
            app.Dot = uibutton(app.Panel_2, 'push');
            app.Dot.ButtonPushedFcn = createCallbackFcn(app, @DotButtonPushed, true);
            app.Dot.Text = '.';
            app.D0 = uibutton(app.Panel_2, 'push');
            app.D0.ButtonPushedFcn = createCallbackFcn(app, @D0ButtonPushed, true);
            app.D0.Text = '0';
            app.clear = uibutton(app.Panel_2, 'push');
            app.clear.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clear.Text = 'C';
            app.Plus = uibutton(app.Panel_2, 'push');
            app.Plus.ButtonPushedFcn = createCallbackFcn(app, @PlusButtonPushed, true);
            app.Plus.Text = '+';
            app.Minus = uibutton(app.Panel_2, 'push');
            app.Minus.ButtonPushedFcn = createCallbackFcn(app, @MinusButtonPushed, true);
            app.Minus.Text = '-';
            app.Mult = uibutton(app.Panel_2, 'push');
            app.Mult.ButtonPushedFcn = createCallbackFcn(app, @MultButtonPushed, true);
            app.Mult.Text = '*';
            app.Div = uibutton(app.Panel_2, 'push');
            app.Div.ButtonPushedFcn = createCallbackFcn(app, @DivButtonPushed, true);
            app.Div.Text = '/';
            app.Result = uibutton(app.Panel_2, 'push');
            app.Result.ButtonPushedFcn = createCallbackFcn(app, @ResultButtonPushed, true);
            app.Result.Text = '=';
            app.Panel_3 = uipanel(app.Panel);
            app.Label_result = uieditfield(app.Panel_3, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end